classdef chwytak < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Panel_laczenia   matlab.ui.container.Panel
        PortLabel        matlab.ui.control.Label
        PortDropDown     matlab.ui.control.DropDown
        PolaczButton     matlab.ui.control.Button
        RozlaczButton    matlab.ui.control.Button
        OdswiezButton    matlab.ui.control.Button
        Panel_regulacji  matlab.ui.container.Panel
        FdLabel          matlab.ui.control.Label
        FdEditField      matlab.ui.control.NumericEditField
        XdLabel          matlab.ui.control.Label
        XdEditField      matlab.ui.control.NumericEditField
        VdLabel          matlab.ui.control.Label
        VdEditField      matlab.ui.control.NumericEditField
        TLabel           matlab.ui.control.Label
        TEditField       matlab.ui.control.NumericEditField
        MLabel           matlab.ui.control.Label
        MEditField       matlab.ui.control.NumericEditField
        BLabel           matlab.ui.control.Label
        BEditField       matlab.ui.control.NumericEditField
        KLabel           matlab.ui.control.Label
        KEditField       matlab.ui.control.NumericEditField
        TrybDropDown     matlab.ui.control.DropDown
        TrybLabel        matlab.ui.control.Label
        Panel_startu     matlab.ui.container.Panel
        StartButton      matlab.ui.control.Button
        StopButton       matlab.ui.control.Button
        ResetButton      matlab.ui.control.Button
        ZapiszButton     matlab.ui.control.Button
        Panel_wynikow    matlab.ui.container.Panel
        FmLabel          matlab.ui.control.Label
        FmEditField      matlab.ui.control.NumericEditField
        XmLabel          matlab.ui.control.Label
        XmEditField      matlab.ui.control.NumericEditField
        VmLabel          matlab.ui.control.Label
        VmEditField      matlab.ui.control.NumericEditField
        ImLabel          matlab.ui.control.Label
        ImEditField      matlab.ui.control.NumericEditField
        Panel_stopki     matlab.ui.container.Panel
        OdczytLabel      matlab.ui.control.Label
        InfoLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        s %obiekt serialport przechowujacy dane o polaczeniu z portem COM
        polaczono %czy polaczono
        start %czy wystartowano
        
        Fm %aktualna sila z czujnikow
        Xm %aktualna pozycja serwa
        Vm %aktualna predkosc serwa
        Im %aktualny prad w serwie
        
        tryb %wybrany rodzaj sterowania
        Fd %zadana sila
        Xd %zadana pozycja
        Vd %zadana predkosc
        M %wsp. M
        B %wsp. B
        K %wsp. K
        T %czas probkowania
        
        fig %okno na wykresy
        tl %layout w oknie na wykresy
        ax %tablica na wykresy sily, pozycji, predkosci i pradu
        h %tablica na linie sily, pozycji, predkosci i pradu
    end
    
    methods (Access = private)
        
        function read_serial(app, src, ~)
            odczyt = readline(src);
            tab = sscanf(odczyt, '%f %f %f %f');
            app.Fm = tab(1);
            app.Xm = tab(2);
            app.Vm = tab(3);
            app.Im = tab(4);
            app.FmEditField.Value = app.Fm;
            app.XmEditField.Value = app.Xm;
            app.VmEditField.Value = app.Vm;
            app.ImEditField.Value = app.Im;
            app.OdczytLabel.Text = odczyt;
        end
        
        function write_serial(app, src, tekst)
            if(app.polaczono)
                for k = 1:length(tekst)
                    write(src, tekst(k), 'char');
                    app.InfoLabel.Text = ['Wysyłanie komendy: ' num2str(ceil(k/length(tekst)*100)) '%']; %postep zapisu
                end
                app.InfoLabel.Text = ['Wysłano komendę: ' tekst];
            else
                app.InfoLabel.Text = 'Błąd: brak połączenia - nie wysłano komendy';
            end
        end
        
        function change_buttons(app, ~, ~)
            if(app.polaczono == 1)
                app.OdczytLabel.Text = 'Brak danych z urządzenia';
                app.Panel_wynikow.Enable = 'on';
                app.Panel_startu.Enable = 'on';
                app.PortDropDown.Enable = 'off';
                app.OdswiezButton.Enable = 'off';
                app.PolaczButton.Enable = 'off';
                app.RozlaczButton.Enable = 'on';
                if(app.start == 1)
                    app.Panel_laczenia.Enable = 'off';
                    app.Panel_regulacji.Enable = 'off';
                    app.StopButton.Enable = 'on';
                    app.ResetButton.Enable = 'off';
                    app.StartButton.Enable = 'off';
                    app.ZapiszButton.Enable = 'off';
                else
                    app.Panel_laczenia.Enable = 'on';
                    app.Panel_regulacji.Enable = 'on';
                    app.StopButton.Enable = 'off';
                    app.ResetButton.Enable = 'on';
                    if(isvalid(app.fig)) %sprawdzenie czy okno wykresow jest poprawne
                        app.StartButton.Enable = 'on';
                        if(isempty(app.ax(1).Children)) %sprawdzenie czy są dane do zapisania
                            app.ZapiszButton.Enable = 'off';
                        else
                            app.ZapiszButton.Enable = 'on';
                        end
                    else
                        app.StartButton.Enable = 'off';
                        app.ZapiszButton.Enable = 'off';
                        app.InfoLabel.Text = 'Błąd: brak okna wykresów - naciśnij "Reset"';
                    end
                    
                    switch(app.tryb)
                        case 1 %regulator pozycyjny
                            app.FdEditField.Enable = 'off';
                            app.FdLabel.Enable = 'off';
                            app.XdEditField.Enable = 'on';
                            app.XdLabel.Enable = 'on';
                            app.VdEditField.Enable = 'off';
                            app.VdLabel.Enable = 'off';
                            app.MEditField.Enable = 'off';
                            app.MLabel.Enable = 'off';
                            app.BEditField.Enable = 'off';
                            app.BLabel.Enable = 'off';
                            app.KEditField.Enable = 'off';
                            app.KLabel.Enable = 'off';
                            app.TEditField.Enable = 'on';
                            app.TLabel.Enable = 'on';
                            
                        case 2 %regulator admitancyjny
                            app.FdEditField.Enable = 'on';
                            app.FdLabel.Enable = 'on';
                            app.XdEditField.Enable = 'off';
                            app.XdLabel.Enable = 'off';
                            app.VdEditField.Enable = 'off';
                            app.VdLabel.Enable = 'off';
                            app.MEditField.Enable = 'on';
                            app.MLabel.Enable = 'on';
                            app.BEditField.Enable = 'on';
                            app.BLabel.Enable = 'on';
                            app.KEditField.Enable = 'on';
                            app.KLabel.Enable = 'on';
                            app.TEditField.Enable = 'on';
                            app.TLabel.Enable = 'on';
                            
                        case 3 %regulator impedancyjny
                            app.FdEditField.Enable = 'off';
                            app.FdLabel.Enable = 'off';
                            app.XdEditField.Enable = 'on';
                            app.XdLabel.Enable = 'on';
                            app.VdEditField.Enable = 'off';
                            app.VdLabel.Enable = 'off';
                            app.MEditField.Enable = 'on';
                            app.MLabel.Enable = 'on';
                            app.BEditField.Enable = 'on';
                            app.BLabel.Enable = 'on';
                            app.KEditField.Enable = 'on';
                            app.KLabel.Enable = 'on';
                            app.TEditField.Enable = 'on';
                            app.TLabel.Enable = 'on';
                            
                        case 4 %regulator sztywnosci
                            app.FdEditField.Enable = 'off';
                            app.FdLabel.Enable = 'off';
                            app.XdEditField.Enable = 'on';
                            app.XdLabel.Enable = 'on';
                            app.VdEditField.Enable = 'off';
                            app.VdLabel.Enable = 'off';
                            app.MEditField.Enable = 'off';
                            app.MLabel.Enable = 'off';
                            app.BEditField.Enable = 'off';
                            app.BLabel.Enable = 'off';
                            app.KEditField.Enable = 'on';
                            app.KLabel.Enable = 'on';
                            app.TEditField.Enable = 'on';
                            app.TLabel.Enable = 'on';
                            
                        case 5 %regulator tlumienia
                            app.FdEditField.Enable = 'off';
                            app.FdLabel.Enable = 'off';
                            app.XdEditField.Enable = 'off';
                            app.XdLabel.Enable = 'off';
                            app.VdEditField.Enable = 'on';
                            app.VdLabel.Enable = 'on';
                            app.MEditField.Enable = 'off';
                            app.MLabel.Enable = 'off';
                            app.BEditField.Enable = 'on';
                            app.BLabel.Enable = 'on';
                            app.KEditField.Enable = 'off';
                            app.KLabel.Enable = 'off';
                            app.TEditField.Enable = 'on';
                            app.TLabel.Enable = 'on';
                            
                        case 6 %rownolegly regulator pozycyjno-silowy
                            app.FdEditField.Enable = 'off';
                            app.FdLabel.Enable = 'off';
                            app.XdEditField.Enable = 'off';
                            app.XdLabel.Enable = 'off';
                            app.VdEditField.Enable = 'on';
                            app.VdLabel.Enable = 'on';
                            app.MEditField.Enable = 'off';
                            app.MLabel.Enable = 'off';
                            app.BEditField.Enable = 'on';
                            app.BLabel.Enable = 'on';
                            app.KEditField.Enable = 'on'; %jako wsp. całkowania C
                            app.KLabel.Enable = 'on'; %jako wsp. całkowania C
                            app.TEditField.Enable = 'on';
                            app.TLabel.Enable = 'on';
                            
                        otherwise
                            app.StartButton.Enable = 'off';
                            
                            app.FdEditField.Enable = 'off';
                            app.FdLabel.Enable = 'off';
                            app.XdEditField.Enable = 'off';
                            app.XdLabel.Enable = 'off';
                            app.VdEditField.Enable = 'off';
                            app.VdLabel.Enable = 'off';
                            app.MEditField.Enable = 'off';
                            app.MLabel.Enable = 'off';
                            app.BEditField.Enable = 'off';
                            app.BLabel.Enable = 'off';
                            app.KEditField.Enable = 'off';
                            app.KLabel.Enable = 'off';
                            app.TEditField.Enable = 'off';
                            app.TLabel.Enable = 'off';
                            
                    end
                end
            else
                app.OdczytLabel.Text = '-';
                app.Panel_wynikow.Enable = 'off';
                app.Panel_startu.Enable = 'off';
                app.PortDropDown.Enable = 'on';
                app.OdswiezButton.Enable = 'on';
                app.PolaczButton.Enable = 'on';
                app.RozlaczButton.Enable = 'off';
                app.Panel_laczenia.Enable = 'on';
                app.Panel_regulacji.Enable = 'off';
                
                app.Fm = 0;
                app.Xm = 0;
                app.Vm = 0;
                app.Im = 0;
                app.FmEditField.Value = 0;
                app.XmEditField.Value = 0;
                app.VmEditField.Value = 0;
                app.ImEditField.Value = 0;
            end
            
            if(app.tryb == 6)
                app.KLabel.Text = 'Wsp. całkowania C'; %wsp. calkowania w rownoleglym regulatorze pozycyjno-silowym
            else
                app.KLabel.Text = 'Wsp. sztywności K'; %w reszcie regulatorow jest to wsp. K (sztywnosc)
            end
            
            %             if(app.tryb == 1) %do sterowania PID pozycją
            %                 app.MLabel.Text = 'Człon P';
            %                 app.BLabel.Text = 'Człon I';
            %                 app.KLabel.Text = 'Człon D';
            %             elseif(app.tryb == 6)
            %                 app.MLabel.Text = 'Wsp. inercji M';
            %                 app.BLabel.Text = 'Wsp. tłumienia B';
            %                 app.KLabel.Text = 'Wsp. całkowania C';
            %             else
            %                 app.MLabel.Text = 'Wsp. inercji M';
            %                 app.BLabel.Text = 'Wsp. tłumienia B';
            %                 app.KLabel.Text = 'Wsp. sztywności K';
            %             end
            
        end
        
        function createFig(app)
            
            %tworzenie okna na wykresy
            app.fig = figure;
            app.fig.Visible = 'off';
            app.fig.Name = 'Wykresy';
            app.fig.NumberTitle = 'off';
            app.fig.MenuBar = 'none';
            app.fig.ToolBar = 'none';
            app.fig.DockControls = 'off';
            app.fig.CloseRequestFcn = '';
            app.fig.Position = [app.UIFigure.Position(1)+app.UIFigure.Position(3)+5 app.UIFigure.Position(2) 900 app.UIFigure.Position(4)];
            
            %podzial okna na 4 czesci
            app.tl = tiledlayout(app.fig, 4, 1);
            app.tl.TileSpacing = 'none';
            app.tl.Padding = 'compact';
            axtoolbar(app.tl,{'export', 'pan', 'zoomin', 'zoomout', 'restoreview'});
            
            %wstawienie 4 wykresow
            app.ax = [nexttile(app.tl), nexttile(app.tl), nexttile(app.tl), nexttile(app.tl)];
            
            %podpisy wykresow i limity
            linkaxes(app.ax, 'x'); %synchronizacja osi x
            xticklabels(app.ax(1),{});
            xticklabels(app.ax(2),{});
            xticklabels(app.ax(3),{});
            xticks(app.ax(1), 'auto');
            xticks(app.ax(2), 'auto');
            xticks(app.ax(3), 'auto');
            app.ax(1).XLim = [0 10]; %domyslny limit osi czasu
            app.ax(2).YLim = [-1 56]; %zakres osi y dla pozycji szczek
            yticks(app.ax(2), [0 10 20 30 40 50]);
            app.tl.XLabel.String = "Czas [s]";
            y_labels = ["Siła chwytu [g]", "Pozycja szczęk [mm]", "Prędkość szczęk [mm/s]", "Pobór prądu [mA]"];
            for i = 1:4
                app.ax(i).Interactions = [];
                app.ax(i).Toolbar.Visible = 'off';
                app.ax(i).YLabel.String = y_labels(i);
                app.ax(i).XGrid = 'on';
                app.ax(i).YGrid = 'on';
                app.ax(i).ColorOrderIndex = 1;
            end
            
            app.fig.Visible = 'on';
        end
        
        function disable_panels(app)
            app.Panel_laczenia.Enable = 'off';
            app.Panel_startu.Enable = 'off';
            app.Panel_regulacji.Enable = 'off';
            app.Panel_wynikow.Enable = 'off';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disable_panels(app);
            app.PortDropDown.Items = serialportlist("all"); % wypelnia liste portami
            
            app.polaczono = 0;
            app.start = 0;
            app.Fm = app.FmEditField.Value;
            app.Xm = app.XmEditField.Value;
            app.Vm = app.VmEditField.Value;
            app.Im = app.ImEditField.Value;
            
            app.tryb = app.TrybDropDown.Value;
            app.Fd = app.FdEditField.Value;
            app.Xd = app.XdEditField.Value;
            app.Vd = app.VdEditField.Value;
            app.M = app.MEditField.Value;
            app.B = app.BEditField.Value;
            app.K = app.KEditField.Value;
            app.T = app.TEditField.Value;
            
            createFig(app);
            app.InfoLabel.Text = 'OK';
            change_buttons(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            disable_panels(app);
            pause(0.1); %sztuczna pauza
            if(isvalid(app.fig)) %sprawdzenie czy jest okno wykresow
                
                %utworzenie 4 nowych linii do wstawienia na wykresy
                app.h = [animatedline(app.ax(1)), animatedline(app.ax(2)), animatedline(app.ax(3)), animatedline(app.ax(4))];
                for i = 1:4
                    app.h(i).LineWidth = 1.5;
                    app.h(i).Color = app.ax(i).ColorOrder(mod(app.ax(i).ColorOrderIndex-1,7)+1, 1:3);
                end
                
                %utworzenie komendy sterujacej
                tekst = sprintf('<%d %0.0f %0.1f %0.1f %0.5f %0.5f %0.5f %0.3f>', ...
                    app.tryb, app.Fd, app.Xd, app.Vd, -app.M, -app.B, -app.K, app.T);
                
                %zapisanie informacji o parametrach regulacji
                app.h(1).UserData = { ...
                    ['Data regulacji: ', datestr(now, 'yyyy-mm-dd HH-MM-SS')]; ...
                    [app.TrybLabel.Text, ': ', num2str(app.tryb), ' - ', char(app.TrybDropDown.Items(app.tryb))]; ...
                    ''; 'Wartości zadane:'; ...
                    [app.FdLabel.Text, ': ', num2str(app.Fd,'%0.0f')]; ...
                    [app.XdLabel.Text, ': ', num2str(app.Xd,'%0.1f')]; ...
                    [app.VdLabel.Text, ': ', num2str(app.Vd,'%0.1f')]; ...
                    [app.MLabel.Text, ': ', num2str(app.M,'%0.5f')]; ...
                    [app.BLabel.Text, ': ', num2str(app.B,'%0.5f')]; ...
                    [app.KLabel.Text, ': ', num2str(app.K,'%0.5f')]; ...
                    [app.TLabel.Text, ': ', num2str(app.T,'%0.3f')]};
                
                write_serial(app, app.s, tekst); %wyslanie komendy do MCU
                app.start = 1;
                app.InfoLabel.Text = 'Rozpoczęto regulację';
                change_buttons(app);
                
                r = rateControl(10); %ustawienie czestotliwosci wykonywania petli
                reset(r);
                %tic; %wlaczenie licznika
                while app.start
                    %t = round(toc,3);
                    t = round(r.TotalElapsedTime,3); %pozyskanie czasu regulacji
                    addpoints(app.h(1), t, app.Fm);
                    addpoints(app.h(2), t, app.Xm);
                    addpoints(app.h(3), t, app.Vm);
                    addpoints(app.h(4), t, app.Im);
                    app.ax(1).XLim = [0 max([1.1*t 10])]; %przesuniecie limitu osi x
                    drawnow limitrate %odswiezenie wykresow
%                     while(toc-t < 0.05)
%                         continue;
%                     end
                    waitfor(r); %czekanie na wykonanie nastepnej iteracji petli
                end
                drawnow
                %disp(statistics(r));
            else
                app.InfoLabel.Text = 'Błąd: brak okna wykresów - nie rozpoczęto regulacji';
            end
            change_buttons(app);
        end

        % Value changed function: TrybDropDown
        function TrybDropDownValueChanged(app, event)
            disable_panels(app);
            app.tryb = app.TrybDropDown.Value;
            change_buttons(app);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disable_panels(app);
            write_serial(app, app.s, 's');
            app.start = 0;
            if(isvalid(app.fig))
                for i = 1:4
                    app.ax(i).ColorOrderIndex = app.ax(i).ColorOrderIndex+1; %zmienia kolor linii
                end
            end
            app.InfoLabel.Text = 'Zatrzymano regulację';
            change_buttons(app);
        end

        % Value changed function: MEditField
        function MEditFieldValueChanged(app, event)
            app.M = app.MEditField.Value;
        end

        % Value changed function: BEditField
        function BEditFieldValueChanged(app, event)
            app.B = app.BEditField.Value;
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            app.K = app.KEditField.Value;
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            app.T = app.TEditField.Value;
        end

        % Value changed function: FdEditField
        function FdEditFieldValueChanged(app, event)
            app.Fd = app.FdEditField.Value;
        end

        % Value changed function: XdEditField
        function XdEditFieldValueChanged(app, event)
            app.Xd = app.XdEditField.Value;
        end

        % Value changed function: VdEditField
        function VdEditFieldValueChanged(app, event)
            app.Vd = app.VdEditField.Value;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            disable_panels(app); %na czas resetu wylacza panele
            pause(0.1); %sztuczna pauza
            if(isvalid(app.fig))
                for i = 1:4
                    delete(app.ax(i).Children); %usuwa wszystkie linie na wykresie
                    app.ax(i).ColorOrderIndex = 1;
                end
                app.ax(1).XLim = [0 10]; %przywraca domyslny limit osi czasu
                app.InfoLabel.Text = 'Zresetowano okno wykresów';
            else
                createFig(app);
                app.InfoLabel.Text = 'Utworzono nowe okno wykresów';
            end
            change_buttons(app); %przywraca panele/przyciski
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if(app.start)
                StopButtonPushed(app);
            end
            if(app.polaczono)
                RozlaczButtonPushed(app);
            end
            
            delete(app.s);
            delete(app.h);
            delete(app.ax);
            delete(app.tl);
            delete(app.fig);
            delete(app);
        end

        % Button pushed function: PolaczButton
        function PolaczButtonPushed(app, event)
            disable_panels(app);
            pause(0.1); %sztuczna pauza
            if(any(app.PortDropDown.Value == serialportlist("available"))) %sprawdzenie dostepnosci portu
                app.s = serialport(app.PortDropDown.Value, 115200); %nawiazanie polaczenia
                configureTerminator(app.s, "CR/LF"); %ustawienie 'terminatora' linii
                flush(app.s); %czyszczenie bufora
                configureCallback(app.s,"terminator",@app.read_serial); %ustawia nasluchiwanie portu
                app.polaczono = 1;
                app.InfoLabel.Text = ['Połączono z "', char(app.s.Port) '"'];
            elseif(any(app.PortDropDown.Value == serialportlist("all")))
                app.polaczono = 0;
                app.InfoLabel.Text = ['Błąd: port "', app.PortDropDown.Value '" jest zajęty - nie połączono'];
            else
                app.polaczono = 0;
                app.InfoLabel.Text = ['Błąd: port "', app.PortDropDown.Value '" jest niepodłączony - nie połączono'];
            end
            change_buttons(app);
        end

        % Button pushed function: RozlaczButton
        function RozlaczButtonPushed(app, event)
            disable_panels(app);
            pause(0.1); %sztuczna pauza
            delete(app.s); %rozlaczenie
            app.polaczono = 0;
            app.InfoLabel.Text = 'Rozłączono z urządzeniem';
            change_buttons(app);
        end

        % Button pushed function: OdswiezButton
        function OdswiezButtonPushed(app, event)
            disable_panels(app);
            pause(0.1); %sztuczna pauza
            app.PortDropDown.Items = serialportlist("all"); % wypelnienie listy portami
            app.InfoLabel.Text = 'Odświeżono listę portów';
            change_buttons(app);
        end

        % Button pushed function: ZapiszButton
        function ZapiszButtonPushed(app, event)
            disable_panels(app); %na czas zapisywania wylacza panele
            pause(0.1); %sztuczna pauza
            if(isvalid(app.fig))
                if(~isempty(app.ax(1).Children)) %sprawdzenie czy są dane do zapisania
                    [~,~] = mkdir('Wyniki'); %tworzy folder na wyniki jesli go nie ma
                    filename = [datestr(now, 'yyyy-mm-dd_HH-MM-SS') '.xlsx'];
                    for c = length(app.ax(1).Children):-1:1
                        
                        [t,x1] = getpoints(app.ax(1).Children(c));
                        [~,x2] = getpoints(app.ax(2).Children(c));
                        [~,x3] = getpoints(app.ax(3).Children(c));
                        [~,x4] = getpoints(app.ax(4).Children(c));
                        
                        Tab = table(t',x1',x2',x3',x4', ...
                            'VariableNames', { ...
                            'Czas [s]', ...
                            'Siła chwytu [g]', ...
                            'Pozycja szczęk [mm]', ...
                            'Prędkość szczęk [mm/s]', ...
                            'Pobór prądu [mA]'});
                        
                        writecell(app.ax(1).Children(c).UserData,['Wyniki\' filename], ...
                            'WriteMode','overwritesheet', ...
                            'Sheet',['Regulacja ' num2str(length(app.ax(1).Children)-c+1)], ...
                            'Range','A1', ...
                            'AutoFitWidth',false);
                        writetable(Tab,['Wyniki\' filename], ...
                            'WriteMode','inplace', ...
                            'Sheet',['Regulacja ' num2str(length(app.ax(1).Children)-c+1)], ...
                            'Range','A13', ...
                            'AutoFitWidth',true);
                    end
                    
                    if(exist(['Wyniki\' filename],'file') == 2) %sprawdzenie czy powstał plik z danymi
                        app.InfoLabel.Text = ['Dane zapisano w "' filename '"'];
                    else
                        app.InfoLabel.Text = 'Błąd: brak pliku - nie zapisano danych';
                    end
                else
                    app.InfoLabel.Text = 'Błąd: brak danych do zapisania - nie zapisano danych';
                end
            else
                app.InfoLabel.Text = 'Błąd: brak okna wykresów - nie zapisano danych';
            end
            change_buttons(app); %przywraca panele/przyciski
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Regulator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_laczenia = uipanel(app.UIFigure);
            app.Panel_laczenia.Enable = 'off';
            app.PortLabel = uilabel(app.Panel_laczenia);
            app.PortLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.Panel_laczenia);
            app.PortDropDown.Items = {};
            app.PortDropDown.Value = {};
            app.PolaczButton = uibutton(app.Panel_laczenia, 'push');
            app.PolaczButton.ButtonPushedFcn = createCallbackFcn(app, @PolaczButtonPushed, true);
            app.PolaczButton.Text = 'Połącz';
            app.RozlaczButton = uibutton(app.Panel_laczenia, 'push');
            app.RozlaczButton.ButtonPushedFcn = createCallbackFcn(app, @RozlaczButtonPushed, true);
            app.RozlaczButton.Text = 'Rozłącz';
            app.OdswiezButton = uibutton(app.Panel_laczenia, 'push');
            app.OdswiezButton.ButtonPushedFcn = createCallbackFcn(app, @OdswiezButtonPushed, true);
            app.OdswiezButton.Icon = 'refresh-button-icon.png';
            app.OdswiezButton.Tooltip = {'Odśwież listę portów'};
            app.OdswiezButton.Text = '';
            app.Panel_regulacji = uipanel(app.UIFigure);
            app.Panel_regulacji.Enable = 'off';
            app.FdLabel = uilabel(app.Panel_regulacji);
            app.FdLabel.Text = 'Siła chwytu [g]';
            app.FdEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.FdEditField.Limits = [0 2000];
            app.FdEditField.RoundFractionalValues = 'on';
            app.FdEditField.ValueDisplayFormat = '%.0f';
            app.FdEditField.ValueChangedFcn = createCallbackFcn(app, @FdEditFieldValueChanged, true);
            app.FdEditField.Value = 1000;
            app.XdLabel = uilabel(app.Panel_regulacji);
            app.XdLabel.Text = 'Pozycja szczęk [mm]';
            app.XdEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.XdEditField.Limits = [0 55];
            app.XdEditField.ValueDisplayFormat = '%.1f';
            app.XdEditField.ValueChangedFcn = createCallbackFcn(app, @XdEditFieldValueChanged, true);
            app.XdEditField.Value = 30;
            app.VdLabel = uilabel(app.Panel_regulacji);
            app.VdLabel.Text = 'Prędkość szczęk [mm/s]';
            app.VdEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.VdEditField.Limits = [-55 0];
            app.VdEditField.ValueDisplayFormat = '%.1f';
            app.VdEditField.ValueChangedFcn = createCallbackFcn(app, @VdEditFieldValueChanged, true);
            app.VdEditField.Value = -5;
            app.TLabel = uilabel(app.Panel_regulacji);
            app.TLabel.Text = 'Czas próbkowania T [s]';
            app.TEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.TEditField.Limits = [0.001 0.1];
            app.TEditField.ValueDisplayFormat = '%.3f';
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 0.005;
            app.MLabel = uilabel(app.Panel_regulacji);
            app.MLabel.Text = 'Wsp. inercji M';
            app.MEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.MEditField.Limits = [-99999 99999];
            app.MEditField.ValueDisplayFormat = '%.5f';
            app.MEditField.ValueChangedFcn = createCallbackFcn(app, @MEditFieldValueChanged, true);
            app.MEditField.Value = 0.1;
            app.BLabel = uilabel(app.Panel_regulacji);
            app.BLabel.Text = 'Wsp. tłumienia B';
            app.BEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.BEditField.Limits = [-99999 99999];
            app.BEditField.ValueDisplayFormat = '%.5f';
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @BEditFieldValueChanged, true);
            app.BEditField.Value = 100;
            app.KLabel = uilabel(app.Panel_regulacji);
            app.KLabel.Text = 'Wsp. sztywności K';
            app.KEditField = uieditfield(app.Panel_regulacji, 'numeric');
            app.KEditField.Limits = [-99999 99999];
            app.KEditField.ValueDisplayFormat = '%.5f';
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Value = 0.01;
            app.TrybDropDown = uidropdown(app.Panel_regulacji);
            app.TrybDropDown.Items = {'regulator pozycyjny', 'regulator admitancyjny', 'regulator impedancyjny', 'regulator sztywności', 'regulator tłumienia', 'równoległy regulator pozycyjno-siłowy'};
            app.TrybDropDown.ItemsData = [1 2 3 4 5 6];
            app.TrybDropDown.ValueChangedFcn = createCallbackFcn(app, @TrybDropDownValueChanged, true);
            app.TrybDropDown.Value = 1;
            app.TrybLabel = uilabel(app.Panel_regulacji);
            app.TrybLabel.Text = 'Tryb regulacji';
            app.Panel_startu = uipanel(app.UIFigure);
            app.Panel_startu.Enable = 'off';
            app.StartButton = uibutton(app.Panel_startu, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.Panel_startu, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.Panel_startu, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ZapiszButton = uibutton(app.Panel_startu, 'push');
            app.ZapiszButton.ButtonPushedFcn = createCallbackFcn(app, @ZapiszButtonPushed, true);
            app.ZapiszButton.Icon = 'save-button-icon.png';
            app.ZapiszButton.Tooltip = {'Zapisz dane z wykresów do pliku'};
            app.ZapiszButton.Text = '';
            app.Panel_wynikow = uipanel(app.UIFigure);
            app.Panel_wynikow.Enable = 'off';
            app.FmLabel = uilabel(app.Panel_wynikow);
            app.FmLabel.Text = 'Siła chwytu [g]';
            app.FmEditField = uieditfield(app.Panel_wynikow, 'numeric');
            app.FmEditField.ValueDisplayFormat = '%.0f';
            app.FmEditField.Editable = 'off';
            app.XmLabel = uilabel(app.Panel_wynikow);
            app.XmLabel.Text = 'Pozycja szczęk [mm]';
            app.XmEditField = uieditfield(app.Panel_wynikow, 'numeric');
            app.XmEditField.ValueDisplayFormat = '%.1f';
            app.XmEditField.Editable = 'off';
            app.VmLabel = uilabel(app.Panel_wynikow);
            app.VmLabel.Text = 'Prędkość szczęk [mm/s]';
            app.VmEditField = uieditfield(app.Panel_wynikow, 'numeric');
            app.VmEditField.ValueDisplayFormat = '%.1f';
            app.VmEditField.Editable = 'off';
            app.ImLabel = uilabel(app.Panel_wynikow);
            app.ImLabel.Text = 'Prąd [mA]';
            app.ImEditField = uieditfield(app.Panel_wynikow, 'numeric');
            app.ImEditField.ValueDisplayFormat = '%.0f';
            app.ImEditField.Editable = 'off';
            app.Panel_stopki = uipanel(app.UIFigure);
            app.OdczytLabel = uilabel(app.Panel_stopki);
            app.OdczytLabel.Text = 'Odczyt';
            app.InfoLabel = uilabel(app.Panel_stopki);
            app.InfoLabel.Text = 'Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = chwytak

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end